classdef GUI2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        SolidityLabel             matlab.ui.control.Label
        SoliditySlider            matlab.ui.control.Slider
        ChooseImageDropDownLabel  matlab.ui.control.Label
        ChooseImageDropDown       matlab.ui.control.DropDown
        EdgeScoreLabel            matlab.ui.control.Label
        EdgeScoreSlider           matlab.ui.control.Slider
        ReporterIntensityLabel    matlab.ui.control.Label
        ReporterIntensitySlider   matlab.ui.control.Slider
        CytoCheckBox              matlab.ui.control.CheckBox
        ChannelsLabel             matlab.ui.control.Label
        NucCheckBox               matlab.ui.control.CheckBox
        ReporterCheckBox          matlab.ui.control.CheckBox
        ChooseStackDropDownLabel  matlab.ui.control.Label
        ChooseStackDropDown       matlab.ui.control.DropDown
        DistToCenterSliderLabel   matlab.ui.control.Label
        DistToCenterSlider        matlab.ui.control.Slider
        UIAxes2                   matlab.ui.control.UIAxes
        Edge2SliderLabel          matlab.ui.control.Label
        Edge2Slider               matlab.ui.control.Slider
    end


    properties (Access = private)
        img_id
        cyto
        nuc
        pdx
        folder
        ResultsTable
        solidity
        EdgeScore
        ReporterIntensity
        displayReporter
        displayNuc
        displayCyto
        DistToCenter
    end

    methods (Access = public)
        
        function results = updateFolder(app)
            inpath = '\\carbon.research.sickkids.ca\rkafri\Miriam\New Experiments\10_7_2016 Mouse islet staining test\Second Torin treatment and recovery\Whole_mount_cy5pdx_cy3E-cad_DAPI_140217';
         
            DAPI = im3dread([inpath app.folder], '*.tif', 1); % channel: DAPI
            ECad = im3dread([inpath app.folder], '*.tif', 2); % channel: e-cadherin
            PDX1 = im3dread([inpath app.folder], '*.tif', 3); % channel: pdx1
            
            padding = 30; % amount of padding around object to keep

            big_blob = imopen(imgaussfilt(ECad,5)>1,strel('disk',20));
            stats = regionprops(big_blob, 'BoundingBox','Area');
            boundingbox = cat(1, stats.BoundingBox);
            area = cat(1, stats.Area);
            [num,max_index] = max(area);
            boundingbox = boundingbox(max_index,:);
            ymin = ceil(boundingbox(1)) - padding;
            if ymin < 1 % out of bounds check
            ymin = 1;
            end
            ymax = ceil(boundingbox(1)) + ceil(boundingbox(4)) + padding;
            if ymax > size(ECad,1) % out of bounds check
            ymax = size(ECad,1);
            end
            xmin = ceil(boundingbox(2)) - padding;
            if xmin < 1 % out of bounds check
            xmin = 1;
            end
            xmax = ceil(boundingbox(2)) + ceil(boundingbox(5)) + padding;
            if xmax > size(ECad,2) % out of bounds check
            xmax = size(ECad,2);
            end
            zmin = ceil(boundingbox(3));
            zmax = ceil(boundingbox(3)) + ceil(boundingbox(6)) - 1;
            %% CROP CYTO
            app.cyto=ECad(xmin:xmax,ymin:ymax,zmin:zmax); % crop            
            %% CROP NUC
            app.nuc=DAPI(xmin:xmax,ymin:ymax,zmin:zmax); % crop
            %% CROP PDX
            app.pdx=PDX1(xmin:xmax,ymin:ymax,zmin:zmax); % crop
        end
        
    
        function results = updateAxes(app)
            subset=app.ResultsTable.Solidity > app.solidity & ...
                app.ResultsTable.EdgeScore < app.EdgeScore &  ...
                app.ResultsTable.ReporterIntensity > app.ReporterIntensity & ...
                app.ResultsTable.DistToCenter > app.DistToCenter;
            subsetTable=app.ResultsTable(subset,:);
 
            %% Bar chart
            cla(app.UIAxes2);
            Means = grpstats(subsetTable.CellSize,subsetTable.Stack,'mean');
            Stds = grpstats(subsetTable.CellSize,subsetTable.Stack,'std');
            Lngth = grpstats(subsetTable.CellSize,subsetTable.Stack,'numel');
            %figure
            bar(app.UIAxes2,Means)
            hold(app.UIAxes2, 'on');
            errorbar(app.UIAxes2,Means,Stds./sqrt(Lngth),'.r','MarkerSize',1)
            set(app.UIAxes2,'FontSize',7,'XTickLabel',unique(subsetTable.Stack))
            
            %% Display RGB Overlay Image
            output_image = zeros(size(app.cyto,1),size(app.cyto,2));
            % Select only cells for the selected folder
            subsetTable=subsetTable(find(strcmp(subsetTable.Folder,{app.folder})),:);

            % Show channels
            if app.displayCyto
                output_image = output_image + double(app.cyto(:,:,str2num(app.img_id)));
            end
            if app.displayReporter
                output_image = output_image + double(app.pdx(:,:,str2num(app.img_id)));
            end
            if app.displayNuc
                output_image = output_image + double(app.nuc(:,:,str2num(app.img_id)));
            end
            labelled_cyto = zeros(size(app.cyto));
            subsetTable = subsetTable(find(strcmp(subsetTable.Folder,{app.folder})),:);
            for i=1:height(subsetTable)
              PixelIdxList = cell2mat(subsetTable{i,{'PixelIdxList'}});
              labelled_cyto(PixelIdxList)=i;
            end
            
            cla(app.UIAxes);
            imshow(output_image,[],'Parent',app.UIAxes);
            hold(app.UIAxes, 'on');
            labelled_cyto_rgb = label2rgb(uint32(labelled_cyto(:,:,str2num(app.img_id))), 'jet', [1 1 1], 'shuffle');
            himage = imshow(labelled_cyto_rgb,[],'Parent',app.UIAxes);
            himage.AlphaData = 0.1;
            axis off
        end    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %addpath 'islet_functions'
            load('ResultsTable2D.mat');
            app.ResultsTable = ResultsTable;
            app.img_id = '4';
            app.EdgeScore = 0;
            app.solidity = 0;
            app.ReporterIntensity = 0;
            app.DistToCenter = 0;
            app.displayReporter = false;
            app.displayNuc = false;
            app.displayCyto = true;
            app.folder = '\ctl_1-0000\ctl_1-0000\';
            
            app.updateFolder();
            app.updateAxes();
            
        end

        % Value changed function: SoliditySlider
        function SoliditySliderValueChanged(app, event)
            app.solidity = app.SoliditySlider.Value;
            app.updateAxes();
        end

        % Value changed function: ChooseImageDropDown
        function ChooseImageDropDownValueChanged(app, event)
            app.img_id = app.ChooseImageDropDown.Value;
            app.updateAxes();
        end

        % Value changed function: EdgeScoreSlider
        function EdgeScoreSliderValueChanged(app, event)
            app.EdgeScore = app.EdgeScoreSlider.Value;
            app.updateAxes();
        end

        % Value changed function: ReporterIntensitySlider
        function ReporterIntensitySliderValueChanged(app, event)
            app.ReporterIntensity = app.ReporterIntensitySlider.Value
            app.updateAxes();
        end

        % Value changed function: CytoCheckBox
        function CytoCheckBoxValueChanged(app, event)
            app.displayCyto = app.CytoCheckBox.Value;
            app.updateAxes();
        end

        % Value changed function: NucCheckBox
        function NucCheckBoxValueChanged(app, event)
            app.displayNuc = app.NucCheckBox.Value;
            app.updateAxes();
            
        end

        % Value changed function: ReporterCheckBox, 
        % ReporterIntensitySlider
        function InsulinCheckBoxValueChanged(app, event)
            app.displayReporter = app.ReporterCheckBox.Value;
            app.updateAxes();
        end

        % Value changed function: ChooseStackDropDown
        function ChooseStackDropDownValueChanged(app, event)
            app.folder = app.ChooseStackDropDown.Value;
            app.updateFolder();
            app.updateAxes();
        end

        % Value changed function: DistToCenterSlider
        function DistToCenterSliderValueChanged(app, event)
            app.DistToCenter = app.DistToCenterSlider.Value
            app.updateAxes();
        end

        % Value changed function: Edge2Slider
        function Edge2SliderValueChanged(app, event)
            value = app.Edge2Slider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.SolidityLabel = uilabel(app.UIFigure);
            app.SolidityLabel.Text = 'Solidity';
            app.SoliditySlider = uislider(app.UIFigure);
            app.SoliditySlider.Limits = [0.6 1];
            app.SoliditySlider.ValueChangedFcn = createCallbackFcn(app, @SoliditySliderValueChanged, true);
            app.SoliditySlider.MinorTicks = [];
            app.SoliditySlider.Value = 0.7;
            app.ChooseImageDropDownLabel = uilabel(app.UIFigure);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.UIFigure);
            app.ChooseImageDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ChooseImageDropDown.Editable = 'on';
            app.ChooseImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseImageDropDownValueChanged, true);
            app.ChooseImageDropDown.Value = '4';
            app.EdgeScoreLabel = uilabel(app.UIFigure);
            app.EdgeScoreLabel.Text = 'EdgeScore';
            app.EdgeScoreSlider = uislider(app.UIFigure);
            app.EdgeScoreSlider.Limits = [-1.1 0];
            app.EdgeScoreSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeScoreSliderValueChanged, true);
            app.EdgeScoreSlider.MinorTicks = [];
            app.ReporterIntensityLabel = uilabel(app.UIFigure);
            app.ReporterIntensityLabel.Text = 'ReporterIntensity';
            app.ReporterIntensitySlider = uislider(app.UIFigure);
            app.ReporterIntensitySlider.Limits = [0 1.1];
            app.ReporterIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @ReporterIntensitySliderValueChanged, true);
            app.ReporterIntensitySlider.MinorTicks = [];
            app.CytoCheckBox = uicheckbox(app.UIFigure);
            app.CytoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CytoCheckBoxValueChanged, true);
            app.CytoCheckBox.Text = 'Cyto';
            app.CytoCheckBox.Value = true;
            app.ChannelsLabel = uilabel(app.UIFigure);
            app.ChannelsLabel.Text = 'Channels';
            app.NucCheckBox = uicheckbox(app.UIFigure);
            app.NucCheckBox.ValueChangedFcn = createCallbackFcn(app, @NucCheckBoxValueChanged, true);
            app.NucCheckBox.Text = 'Nuc';
            app.ReporterCheckBox = uicheckbox(app.UIFigure);
            app.ReporterCheckBox.ValueChangedFcn = createCallbackFcn(app, @InsulinCheckBoxValueChanged, true);
            app.ReporterCheckBox.Text = 'Reporter';
            app.ChooseStackDropDownLabel = uilabel(app.UIFigure);
            app.ChooseStackDropDownLabel.Text = 'Choose Stack';
            app.ChooseStackDropDown = uidropdown(app.UIFigure);
            app.ChooseStackDropDown.Items = {'\ctl_1-0000\ctl_1-0000\', '\ctl_1-0001\ctl_1-0001\', '\ctl_1-0002\ctl_1-0002\', '\ctl_1-0004\ctl_1-0004\', '\ctl_1-0005\ctl_1-0005\', '\Torin_1-0000\Torin_1-0000\', '\Torin_1-0003\Torin_1-0003\', '\Torin_1-0004\Torin_1-0004\', '\Torin_1-0005\Torin_1-0005\', '\Torin_1-0006\Torin_1-0006\', '\Torin_wash_1-0000\Torin_wash_1-0000\', '\Torin_wash_1-0001\Torin_wash_1-0001\', '\Torin_wash_1-0002\Torin_wash_1-0002\', '\Torin_wash_1-0003\Torin_wash_1-0003\', '\Torin_wash_1-0005\Torin_wash_1-0005\'};
            app.ChooseStackDropDown.Editable = 'on';
            app.ChooseStackDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseStackDropDownValueChanged, true);
            app.ChooseStackDropDown.Value = '\ctl_1-0000\ctl_1-0000\';
            app.DistToCenterSliderLabel = uilabel(app.UIFigure);
            app.DistToCenterSliderLabel.Text = 'DistToCenter';
            app.DistToCenterSlider = uislider(app.UIFigure);
            app.DistToCenterSlider.Limits = [0 500];
            app.DistToCenterSlider.ValueChangedFcn = createCallbackFcn(app, @DistToCenterSliderValueChanged, true);
            app.DistToCenterSlider.MinorTicks = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Cell Area')
            app.Edge2SliderLabel = uilabel(app.UIFigure);
            app.Edge2SliderLabel.Text = 'Edge2';
            app.Edge2Slider = uislider(app.UIFigure);
            app.Edge2Slider.Limits = [0 500];
            app.Edge2Slider.ValueChangedFcn = createCallbackFcn(app, @Edge2SliderValueChanged, true);
            app.Edge2Slider.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI2017

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end